classdef airfoilData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ReynoldsnoEditFieldLabel   matlab.ui.control.Label
        ReynoldsnoEditField        matlab.ui.control.NumericEditField
        ThicknessratioLabel        matlab.ui.control.Label
        ThicknessratioEditField    matlab.ui.control.NumericEditField
        LiftCurve                  matlab.ui.control.UIAxes
        RUNButton                  matlab.ui.control.Button
        DragPolar                  matlab.ui.control.UIAxes
        UITable                    matlab.ui.control.Table
        ExportdataCheckBox         matlab.ui.control.CheckBox
        AirfoilclassDropDownLabel  matlab.ui.control.Label
        AirfoilclassDropDown       matlab.ui.control.DropDown
        AirfoilexperimentaldatabaseLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            airfoilClass = app.AirfoilclassDropDown.Value;
            reynoldsNumber = app.ReynoldsnoEditField.Value;
            thicknessRatio = app.ThicknessratioEditField.Value;
            
            switch airfoilClass
                case 'NACA 4 sym'
                    airfoilType = {'4s'};
                case 'NACA 4 camb'
                    airfoilType = {'4a'};
                case 'NACA 5 sym'
                    airfoilType = {'5s'};
                case 'NACA 5 camb'
                    airfoilType = {'5a'};
                case 'NACA 6 sym'
                    airfoilType = {'6s'};
                case 'NACA 6 camb'
                    airfoilType = {'6a'};
                case 'NASA supercritical'
                    airfoilType = {'sc'};
            end
            
            % Query airfoil database
            [localClMax, localClAlfa, localAlfaStar, localAlfaStall, localClZero,...
                localAlfaZeroLift, localCdMin, localK, localCli, localCmc4] = ...
                airfoilDatabase(1,airfoilType,reynoldsNumber,thicknessRatio);
            localClStar = localClAlfa .* (localAlfaStar - localAlfaZeroLift);
            
            % Evaluate airfoil curves
            A = [localAlfaStar^3, localAlfaStar^2, localAlfaStar, 1; ...
                localAlfaStall^3, localAlfaStall^2, localAlfaStall, 1; ...
                3*localAlfaStar^2, 2*localAlfaStar, localAlfaStar, 0; ...
                3*localAlfaStall^2, 2*localAlfaStall, localAlfaStall, 0];
            b = [localClStar, localClMax, localClAlfa, 0]';
            x = A\b;
            
            % Lift curve
            alfaLinear = localAlfaZeroLift:localAlfaStar;
            alfaNonLinear = localAlfaStar:localAlfaStall+2;
            clLinear = localClAlfa .* (alfaLinear - localAlfaZeroLift);
            clNonLinear = x(1)*alfaNonLinear.^3 + x(2)*alfaNonLinear.^2 + ...
                x(3)*alfaNonLinear + x(4);
            
            % Use a parabola instead of a cubic curve, if the reconstructed curve goes
            % beyond Clmax, which falls on a local minimum instead of the maximum
            if round(max(clNonLinear),2) > round(localClMax,2)
                A = [localAlfaStar^2, localAlfaStar, 1; ...
                    localAlfaStall^2, localAlfaStall, 1; ...
                    2*localAlfaStall, 1, 0];
                b = [localClStar, localClMax, 0]';
                x = A\b;
                clNonLinear = x(1)*alfaNonLinear.^2 + x(2)*alfaNonLinear + x(3);
                % disp('Using a parabola instead of a cubic curve')
            end
            
            % Cut the curve if in post-stall decrease too much
            while clNonLinear(end) < localClStar
                alfaNonLinear = alfaNonLinear(1:end-1);
                clNonLinear = clNonLinear(1:end-1);
            end
            
            alfaArray = [alfaLinear, alfaNonLinear];
            clArray = [clLinear, clNonLinear];
            
            % Drag polar
            cdArray = localCdMin + localK * (clArray - localCli).^2;
            
            % Plot
            plot(app.LiftCurve,alfaArray,clArray,'k','LineWidth',2)
            plot(app.DragPolar,cdArray,clArray,'k','LineWidth',2)
            app.DragPolar.XLim(1) = 0;
            app.DragPolar.XLim(2) = round(max(cdArray)+0.001,3);
            app.DragPolar.YLim(2) = app.LiftCurve.YLim(2);
            
            % Write data in app table
            app.UITable.Data = [
                {'alpha zero lift (deg)'}, {num2str(localAlfaZeroLift,'%1.1f')}; ...
                {'alpha star (deg)'}, {num2str(localAlfaStar,'%1.1f')}; ...
                {'alpha stall (deg)'}, {num2str(localAlfaStall,'%1.1f')}; ...
                {'Cl zero'}, {num2str(localClZero,'%1.2f')}; ...
                {'Cl ideal'}, {num2str(localCli,'%1.2f')}; ...
                {'Cl_alpha (/deg)'}, {num2str(localClAlfa,'%1.3f')}; ...
                {'Cl star'}, {num2str(localClStar,'%1.2f')}; ...
                {'Cl max'}, {num2str(localClMax,'%1.2f')}; ...
                {'Cd min'}, {num2str(localCdMin,'%1.4f')}; ...
                {'K parabolic'}, {num2str(localK,'%1.4f')}; ...
                {'Cm c/4'}, {num2str(localCmc4,'%1.4f')}];
            
            % Export table, if desired (export of charts not yet available in R2017b)
            exportFlag = app.ExportdataCheckBox.Value;
            if exportFlag == 1
                xlswrite('myAirfoilData.xls',app.UITable.Data);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ReynoldsnoEditFieldLabel = uilabel(app.UIFigure);
            app.ReynoldsnoEditFieldLabel.Text = 'Reynolds no.';
            app.ReynoldsnoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReynoldsnoEditField.Limits = [100000 1000000000];
            app.ReynoldsnoEditField.Value = 1000000;
            app.ThicknessratioLabel = uilabel(app.UIFigure);
            app.ThicknessratioLabel.Text = 'Thickness ratio (%)';
            app.ThicknessratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessratioEditField.Limits = [6 22];
            app.ThicknessratioEditField.Value = 12;
            app.LiftCurve = uiaxes(app.UIFigure);
            xlabel(app.LiftCurve, 'Angle of attack, deg')
            ylabel(app.LiftCurve, 'Lift coefficient')
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.DragPolar = uiaxes(app.UIFigure);
            xlabel(app.DragPolar, 'Drag coefficient')
            ylabel(app.DragPolar, 'Lift coefficient')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.ExportdataCheckBox = uicheckbox(app.UIFigure);
            app.ExportdataCheckBox.Text = 'Export data';
            app.AirfoilclassDropDownLabel = uilabel(app.UIFigure);
            app.AirfoilclassDropDownLabel.Text = 'Airfoil class';
            app.AirfoilclassDropDown = uidropdown(app.UIFigure);
            app.AirfoilclassDropDown.Items = {'NACA 4 sym', 'NACA 4 camb', 'NACA 5 sym', 'NACA 5 camb', 'NACA 6 sym', 'NACA 6 camb', 'NASA supercritical'};
            app.AirfoilclassDropDown.Value = 'NACA 4 sym';
            app.AirfoilexperimentaldatabaseLabel = uilabel(app.UIFigure);
            app.AirfoilexperimentaldatabaseLabel.Text = 'Airfoil experimental database';
        end
    end

    methods (Access = public)

        % Construct app
        function app = airfoilData

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end