classdef airfoilData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AirfoilExperimentalDatabaseUIFigure  matlab.ui.Figure
        ReynoldsnumberEditFieldLabel  matlab.ui.control.Label
        ReynoldsnumberEditField       matlab.ui.control.NumericEditField
        ThicknessratioLabel           matlab.ui.control.Label
        ThicknessratioEditField       matlab.ui.control.NumericEditField
        LiftCurve                     matlab.ui.control.UIAxes
        RUNButton                     matlab.ui.control.Button
        DragPolar                     matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
        ExportdataCheckBox            matlab.ui.control.CheckBox
        AirfoilclassDropDownLabel     matlab.ui.control.Label
        AirfoilclassDropDown          matlab.ui.control.DropDown
        AirfoilexperimentaldatabaseLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            airfoilClass = app.AirfoilclassDropDown.Value;
            reynoldsNumber = app.ReynoldsnumberEditField.Value;
            thicknessRatio = app.ThicknessratioEditField.Value;
            
            switch airfoilClass
                case 'NACA 4 symmetric'
                    airfoilType = {'4s'};
                case 'NACA 4 cambered'
                    airfoilType = {'4a'};
                case 'NACA 5 symmetric'
                    airfoilType = {'5s'};
                case 'NACA 5 cambered'
                    airfoilType = {'5a'};
                case 'NACA 6 symmetric'
                    airfoilType = {'6s'};
                case 'NACA 6 cambered'
                    airfoilType = {'6a'};
                case 'NASA supercritical'
                    airfoilType = {'sc'};
            end
            
            % Query airfoil database
            [localClMax, localClAlfa, localAlfaStar, localAlfaStall, localClZero,...
                localAlfaZeroLift, localCdMin, localK, localCli, localCmc4] = ...
                airfoilDatabase(1,airfoilType,reynoldsNumber,thicknessRatio);
            localClStar = localClAlfa .* (localAlfaStar - localAlfaZeroLift);
            
            % Lift curve: linear range
            alfaLinear = linspace(localAlfaZeroLift,localAlfaStar);
            clLinear = localClAlfa * (alfaLinear - localAlfaZeroLift);
            
            % Lift curve: non-linear range
            alfa1 = (localClMax - localClStar) / localClAlfa + localAlfaStar;
            if alfa1 > localAlfaStall
                localAlfaStall = alfa1; % otherwise slope in the non-linear range increases!
            end
            cl1 = localClMax;
            
            c = 0;
            for t = 0:0.1:1     % reconstruction with a quadratic Bezier curve
                c = c + 1;
                alfaNonLinear(c) = (1-t)^2*localAlfaStar + 2*t*(1-t)*alfa1 + t^2*localAlfaStall; %#ok<*SAGROW>
                clNonLinear(c) = (1-t)^2*localClStar + 2*t*(1-t)*cl1 + t^2*localClMax;
            end
            
            alfaArray = [alfaLinear, alfaNonLinear];
            clArray = [clLinear, clNonLinear];
            
            % Drag polar
            cdArray = localCdMin + localK * (clArray - localCli).^2;
            
            % Plot
            plot(app.LiftCurve,alfaArray,clArray,'k','LineWidth',2)
            plot(app.DragPolar,cdArray,clArray,'k','LineWidth',2)
            app.DragPolar.XLim(1) = 0;
            app.DragPolar.XLim(2) = round(max(cdArray)+0.001,3);
            app.DragPolar.YLim(2) = app.LiftCurve.YLim(2);
            
            % Write data in app table
            app.UITable.Data = [
                {'alpha zero lift (deg)'}, {num2str(localAlfaZeroLift,'%1.1f')}; ...
                {'alpha star (deg)'}, {num2str(localAlfaStar,'%1.1f')}; ...
                {'alpha stall (deg)'}, {num2str(localAlfaStall,'%1.1f')}; ...
                {'Cl zero'}, {num2str(localClZero,'%1.2f')}; ...
                {'Cl ideal'}, {num2str(localCli,'%1.2f')}; ...
                {'Cl_alpha (/deg)'}, {num2str(localClAlfa,'%1.3f')}; ...
                {'Cl star'}, {num2str(localClStar,'%1.2f')}; ...
                {'Cl max'}, {num2str(localClMax,'%1.2f')}; ...
                {'Cd min'}, {num2str(localCdMin,'%1.4f')}; ...
                {'K parabolic'}, {num2str(localK,'%1.4f')}; ...
                {'Cm c/4'}, {num2str(localCmc4,'%1.4f')}];
            
            % Export table, if desired (export of charts not yet available in R2017b)
            exportFlag = app.ExportdataCheckBox.Value;
            if exportFlag == 1
                xlswrite('myAirfoilData.xls',app.UITable.Data);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AirfoilExperimentalDatabaseUIFigure = uifigure;
            app.AirfoilExperimentalDatabaseUIFigure.Name = 'Airfoil Experimental Database';
            app.ReynoldsnumberEditFieldLabel = uilabel(app.AirfoilExperimentalDatabaseUIFigure);
            app.ReynoldsnumberEditFieldLabel.Text = 'Reynolds number';
            app.ReynoldsnumberEditField = uieditfield(app.AirfoilExperimentalDatabaseUIFigure, 'numeric');
            app.ReynoldsnumberEditField.Limits = [100000 1000000000];
            app.ReynoldsnumberEditField.Value = 1000000;
            app.ThicknessratioLabel = uilabel(app.AirfoilExperimentalDatabaseUIFigure);
            app.ThicknessratioLabel.Text = 'Thickness ratio (%)';
            app.ThicknessratioEditField = uieditfield(app.AirfoilExperimentalDatabaseUIFigure, 'numeric');
            app.ThicknessratioEditField.Limits = [6 22];
            app.ThicknessratioEditField.Value = 12;
            app.LiftCurve = uiaxes(app.AirfoilExperimentalDatabaseUIFigure);
            xlabel(app.LiftCurve, 'Angle of attack, deg')
            ylabel(app.LiftCurve, 'Lift coefficient')
            app.RUNButton = uibutton(app.AirfoilExperimentalDatabaseUIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.DragPolar = uiaxes(app.AirfoilExperimentalDatabaseUIFigure);
            xlabel(app.DragPolar, 'Drag coefficient')
            ylabel(app.DragPolar, 'Lift coefficient')
            app.UITable = uitable(app.AirfoilExperimentalDatabaseUIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.ExportdataCheckBox = uicheckbox(app.AirfoilExperimentalDatabaseUIFigure);
            app.ExportdataCheckBox.Text = 'Export data';
            app.AirfoilclassDropDownLabel = uilabel(app.AirfoilExperimentalDatabaseUIFigure);
            app.AirfoilclassDropDownLabel.Text = 'Airfoil class';
            app.AirfoilclassDropDown = uidropdown(app.AirfoilExperimentalDatabaseUIFigure);
            app.AirfoilclassDropDown.Items = {'NACA 4 symmetric', 'NACA 4 cambered', 'NACA 5 symmetric', 'NACA 5 cambered', 'NACA 6 symmetric', 'NACA 6 cambered', 'NASA supercritical'};
            app.AirfoilclassDropDown.Value = 'NACA 4 symmetric';
            app.AirfoilexperimentaldatabaseLabel = uilabel(app.AirfoilExperimentalDatabaseUIFigure);
            app.AirfoilexperimentaldatabaseLabel.Text = 'Airfoil experimental database';
        end
    end

    methods (Access = public)

        % Construct app
        function app = airfoilData

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AirfoilExperimentalDatabaseUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AirfoilExperimentalDatabaseUIFigure)
        end
    end
end